classdef IterativeMethodAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IterativeMethodUIFigure  matlab.ui.Figure
        Panel_2                  matlab.ui.container.Panel
        gxEditFieldLabel         matlab.ui.control.Label
        gxEditField              matlab.ui.control.EditField
        x0EditFieldLabel         matlab.ui.control.Label
        x0EditField              matlab.ui.control.NumericEditField
        qEditFieldLabel          matlab.ui.control.Label
        qEditField               matlab.ui.control.NumericEditField
        epsLabel                 matlab.ui.control.Label
        epsEditField             matlab.ui.control.NumericEditField
        MethodInputsLabel        matlab.ui.control.Label
        PlotfunctionButton       matlab.ui.control.Button
        ToleranceLabel           matlab.ui.control.Label
        FindRootButton           matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        GotoendButton            matlab.ui.control.Button
        NextiterationButton      matlab.ui.control.Button
        IterativefunctionLabel   matlab.ui.control.Label
        AutoCheckBox             matlab.ui.control.CheckBox
        aEditFieldLabel          matlab.ui.control.Label
        aEditField               matlab.ui.control.NumericEditField
        bEditFieldLabel          matlab.ui.control.Label
        bEditField               matlab.ui.control.NumericEditField
        nEditFieldLabel          matlab.ui.control.Label
        nEditField               matlab.ui.control.NumericEditField
        ResumeButton             matlab.ui.control.Button
        ClearAllButton           matlab.ui.control.Button
        PlottingonlyButton       matlab.ui.control.StateButton
        Panel                    matlab.ui.container.Panel
        FunctionplotLabel        matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        OgnjenSimic9318Label     matlab.ui.control.Label
        Panel_3                  matlab.ui.container.Panel
        ErrorplotLabel           matlab.ui.control.Label
        UIAxes2                  matlab.ui.control.UIAxes
        ResultsLabel             matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        ROOTEditFieldLabel       matlab.ui.control.Label
        ROOTEditField            matlab.ui.control.NumericEditField
        ResultLabel              matlab.ui.control.Label
    end


    properties (Access = private)
        % property shared btw findRootCallback and buttons that manually control algorithm flow
        % property is changed on button callbacks
        % Ognjen Simic 93/18
        
        isClickedNextIteration = 0;
        isClickedgotoEnd = 0;
        isClickedStop = 0;
        isClickedPlottingOnly = 0 % Description
    end

    methods (Access = private)
    
        function plotFunc(app,g,a,b)
            hold(app.UIAxes,'off');
            x = linspace(a,b,100);
            plot(app.UIAxes,x,g(x));
            hold(app.UIAxes,'on');
            plot(app.UIAxes,x,x);
            f = @(x) x - x;
            hold(app.UIAxes,'on');
            plot(app.UIAxes,x,f(x),'k');
            legend(app.UIAxes, 'g(x)','y = x','x-osa');
        end
        
        function isOk = validate(app,n,g,a,b,q,x0)
            errors = '';
            if (n == 0) 
                app.TextArea.Value = ['Method inputs (arguments) error!' newline 'Please check your inputs.'];
                errors = 'error';
            end    
            if (strcmp(g,'')) 
                app.TextArea.Value = ['Method inputs (arguments) error!' newline 'Please check your inputs.'];
                errors = 'error';
            end    
            if (a > b)
                app.TextArea.Value = ['Method inputs (arguments) error!' newline 'Please check your inputs.'];
                errors = 'error';
            end 
            if (q > 1) 
                app.TextArea.Value = ['Method inputs (arguments) error!' newline 'Please check your inputs.'];
                errors = 'error';
            end
            if (x0 < a || x0 > b) 
                app.TextArea.Value = ['Method inputs (arguments) error!' newline 'Please check your inputs.'];
                errors = 'error';
            end   
            if (~strcmp(errors,'')) 
                isOk = -1;
            else
                isOk = 1;
            end    
        end
        
        function mydialog(app)
        d = dialog('Position',[300 300 250 150],'Name','Function View');

        txt = uicontrol('Parent',d,...
               'Style','text',...
               'Position',[20 80 210 40],...
               'String','Click the close button when you''re done.');

        btn = uicontrol('Parent',d,...
               'Position',[85 20 70 25],...
               'String','Close',...
               'Callback','delete(gcf)');
           
        uiaxes = uicontrol('Parent',d...
                );
        
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.NextiterationButton,'Enable','off');
            set(app.GotoendButton,'Enable','off');
            set(app.StopButton,'Enable','off');
            set(app.PlotfunctionButton,'Enable','off');
            set(app.FindRootButton,'Interruptible', 'on');
            set(app.ResumeButton,'Enable','off');
        end

        % Button pushed function: PlotfunctionButton
        function PlotfunctionButtonPushed(app, event)
            try 
                g = str2func(['@(x) ',app.gxEditField.Value]);
                a = app.aEditField.Value;
                b = app.bEditField.Value;
                if (a == b) 
                    app.TextArea.Value = ['Warning!' newline 'Plotting is finished but provided interval is [0,0]!'];
                end 
                plotFunc(app,g,a,b);
            catch ME
                app.TextArea.Value = sprintf('%s\n%s','Error occured during ploting function g(x)',ME.message);
            end
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            % implementation phase - dodati event handler
        end

        % Button pushed function: FindRootButton
        function FindRootButtonPushed(app, event)
            autoOn = app.AutoCheckBox.Value;
            g = app.gxEditField.Value;
            n = app.nEditField.Value;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            x0 = app.x0EditField.Value;
            q = app.qEditField.Value;
            eps = app.epsEditField.Value;
            if (validate(app,n,g,a,b,q,x0) == 1) 
                if (autoOn == 1)
                    set(app.NextiterationButton,'Enable','off');
                    set(app.StopButton,'Enable','on');
                else
                    set(app.NextiterationButton,'Enable','on');
                    set(app.StopButton,'Enable','off');
                end    
                set(app.GotoendButton,'Enable','on');
                % Disable input fields while iterative algorithm is working
                set(app.gxEditField,'Enable','off');
                set(app.x0EditField,'Enable','off');
                set(app.PlottingonlyButton,'Enable','off');
                set(app.qEditField,'Enable','off');
                set(app.epsEditField,'Enable','off');
                set(app.PlotfunctionButton,'Enable','off');
                set(app.aEditField,'Enable','off');
                set(app.bEditField,'Enable','off');
                set(app.nEditField,'Enable','off');
                set(app.AutoCheckBox,'Enable','off');
                set(app.ClearAllButton,'Enable','off');
                % .
                % Plot function again (Drop initial plotting values)
                try 
                g = str2func(['@(x) ',app.gxEditField.Value]);
                a = app.aEditField.Value;
                b = app.bEditField.Value;
                if (a == b) 
                    app.TextArea.Value = ['Warning!' newline 'Plotting is finished but provided interval is [0,0]!'];
                end 
                plotFunc(app,g,a,b);
                catch ME
                    app.TextArea.Value = sprintf('%s\n%s','Error occured during ploting function g(x)',ME.message);
                    
                end
                % .
                g = str2func(['@(x) ',app.gxEditField.Value]);
                % reset values
                app.TextArea.Value = '';
                app.ROOTEditField.Value = 0;
                % .
                if (autoOn == 0)
                    x = [x0]; 
                    m = -1;
                    format long
                    hold(app.UIAxes,'on');
                    hold(app.UIAxes2,'on');
                    h = animatedline(app.UIAxes,'Color','r','LineStyle',':','LineWidth',1.3,'MarkerSize',8);
                    legend(app.UIAxes, 'g(x)','y = x','x-osa','Ialgorithm','AutoUpdate','off');
                    text(app.UIAxes,x0,0,'x0');
                    set(app.UIAxes,'DefaultLegendAutoUpdate','off');
                    for i = 1:n
                        gotoEnd = app.isClickedgotoEnd;
                        if (i ~= 1)
                            if (gotoEnd == 0)
                                while 1
                                    pause(0.5);
                                    isClickedNI = app.isClickedNextIteration; 
                                    isClickedGE = app.isClickedgotoEnd;
                                    if (isClickedNI == 1)
                                        break;
                                    end    
                                    if (isClickedGE == 1)
                                        break;
                                    end    
                                end
                            end
                        end    
                        hold(app.UIAxes,'on');
                        x(i+1) = g(x(i));
                        apostg = (q/(1-q))* (abs(x(i+1)-x(i)));
                        if (i ~= 1)
                            apostgB = (q/(1-q))*(abs(x(i)-x(i-1)));
                            semilogy(app.UIAxes2,[i-1,i],[apostgB,apostg],'k');
                            scatter(app.UIAxes2,i,apostg,'r');
                        else
                            hold(app.UIAxes2,'off');
                            scatter(app.UIAxes2,i,apostg,'r');
                            hold(app.UIAxes2,'on');
                        end    
                        gotoEnd = app.isClickedgotoEnd;
                        if (gotoEnd == 0) 
                            xosa = x(i);
                            yosa = linspace(0,g(x(i)),12);
                            for k = 1:length(yosa)
                            addpoints(h,xosa,yosa(k));
                            drawnow
                            end
                            xosa = linspace(x(i),g(x(i)),12);
                            yosa = g(x(i));
                            for k = 1:length(xosa)
                            addpoints(h,xosa(k),yosa);
                            drawnow
                            end
                            xosa = g(x(i));
                            yosa = linspace(g(x(i)),0,12);
                            for k = 1:length(yosa)
                            addpoints(h,xosa,yosa(k));
                            drawnow
                            end
                        else
                            plot(app.UIAxes,[x(i),x(i)],[0,g(x(i))],'Color','r','LineStyle',':','LineWidth',1.3,'MarkerSize',8);
                            plot(app.UIAxes,[x(i),g(x(i))],[g(x(i)),g(x(i))],'Color','r','LineStyle',':','LineWidth',1.3,'MarkerSize',8);
                            plot(app.UIAxes,[g(x(i)),g(x(i))],[g(x(i)),0],'Color','r','LineStyle',':','LineWidth',1.3,'MarkerSize',8);
                        end    
                        text(app.UIAxes,g(x(i)),0,['x',num2str(i)]);
                        cellArrayText{i} = sprintf('%s\n%s\n%s', [num2str(i),'. iteracija: ', ...
                            'x(',num2str(i),') = ',num2str(x(i+1)),' ,  f(x(', ...
                            num2str(i),')) = ', num2str(g(x(i)))],[ ...
                            'Aposteriori greska: ',num2str(apostg)], ...
                        '----------------------------------------------------'); 
                        app.TextArea.Value = cellArrayText;
                        if apostg < eps 
                            cellArrayText{i+1} = ['Tolerancija zadovoljena nakon ',num2str(i),' iteracija'];
                            app.TextArea.Value = cellArrayText;
                            m = i+1;
                            app.isClickedNextIteration = 0;
                            break
                        end 
                        %reset isClickedNextIteration 
                        app.isClickedNextIteration = 0;
                    end    
                else
                    x = [x0]; 
                    m = -1;
                    format long
                    hold(app.UIAxes,'on');
                    h = animatedline(app.UIAxes,'Color','r','LineStyle',':','LineWidth',1.3,'MarkerSize',8);
                    legend(app.UIAxes, 'g(x)','y = x','x-osa','Ialgorithm','AutoUpdate','off');
                    text(app.UIAxes,x0,0,'x0');
                    set(app.UIAxes,'DefaultLegendAutoUpdate','off');
                    for i = 1:n
                        gotoEnd = app.isClickedgotoEnd;
                        hold(app.UIAxes,'on');
                        hold(app.UIAxes2,'on');
                        x(i+1) = g(x(i));
                        apostg = (q/(1-q))* (abs(x(i+1)-x(i)));
                        if (i ~= 1)
                            apostgB = (q/(1-q))*(abs(x(i)-x(i-1)));
                            semilogy(app.UIAxes2,[i-1,i],[apostgB,apostg],'k');
                            scatter(app.UIAxes2,i,apostg,'r');
                        else
                            hold(app.UIAxes2,'off');
                            scatter(app.UIAxes2,i,apostg,'r');
                            hold(app.UIAxes2,'on');
                        end  
                        if (gotoEnd == 0) 
                            xosa = x(i);
                            yosa = linspace(0,g(x(i)),12);
                            for k = 1:length(yosa)
                            addpoints(h,xosa,yosa(k));
                            drawnow
                            end
                            xosa = linspace(x(i),g(x(i)),12);
                            yosa = g(x(i));
                            for k = 1:length(xosa)
                            addpoints(h,xosa(k),yosa);
                            drawnow
                            end
                            xosa = g(x(i));
                            yosa = linspace(g(x(i)),0,12);
                            for k = 1:length(yosa)
                            addpoints(h,xosa,yosa(k));
                            drawnow
                            end
                        else
                            plot(app.UIAxes,[x(i),x(i)],[0,g(x(i))],'Color','r','LineStyle',':','LineWidth',1.3,'MarkerSize',8);
                            plot(app.UIAxes,[x(i),g(x(i))],[g(x(i)),g(x(i))],'Color','r','LineStyle',':','LineWidth',1.3,'MarkerSize',8);
                            plot(app.UIAxes,[g(x(i)),g(x(i))],[g(x(i)),0],'Color','r','LineStyle',':','LineWidth',1.3,'MarkerSize',8);
                        end    
                        text(app.UIAxes,g(x(i)),0,['x',num2str(i)]);
                        cellArrayText{i} = sprintf('%s\n%s\n%s', [num2str(i),'. iteracija: ', ...
                            'x(',num2str(i),') = ',num2str(x(i+1)),' ,  f(x(', ...
                            num2str(i),')) = ', num2str(g(x(i)))],[ ...
                            'Aposteriori greska: ',num2str(apostg)], ...
                        '----------------------------------------------------'); 
                        app.TextArea.Value = cellArrayText;
                        if apostg < eps 
                            cellArrayText{i+1} = ['Tolerancija zadovoljena nakon ',num2str(i),' iteracija'];
                            app.TextArea.Value = cellArrayText;
                            m = i+1;
                            break
                        end 
                        if (gotoEnd == 0)
                            pause(2);
                        end
                    end
                end 
                % algorithm finished
                        if (apostg < eps)
                            text2 = 'Tolerance fulfilled';
                        else
                            text2 = 'Tolerance was not fulfilled';
                        end    
                        cellArrayText{i+1} = sprintf('%s\n%s','Iterative algorithm finished',text2);
                        app.TextArea.Value = cellArrayText;
                % ROOT
                if m ~= -1
                    app.ROOTEditField.Value = x(m);
                else 
                    app.ROOTEditField.Value = x(n+1);
                end      
                app.isClickedgotoEnd = 0;
                %Enable input fields after algorithm finished
                set(app.gxEditField,'Enable','on');
                set(app.x0EditField,'Enable','on');
                set(app.qEditField,'Enable','on');
                set(app.epsEditField,'Enable','on');
                set(app.PlotfunctionButton,'Enable','on');
                set(app.aEditField,'Enable','on');
                set(app.bEditField,'Enable','on');
                set(app.nEditField,'Enable','on');
                set(app.AutoCheckBox,'Enable','on');
                set(app.ClearAllButton,'Enable','on');
                set(app.PlottingonlyButton,'Enable','on');
                % .
            end
        end

        % Value changed function: gxEditField
        function gxEditFieldValueChanged(app, event)
            g = app.gxEditField.Value;
            set(app.PlotfunctionButton,'Enable','off');
            if (strcmp(g,'') == 0) 
                if (strcmp(g,' ') == 0)
                set(app.PlotfunctionButton,'Enable','on');
                end
            end   
        end

        % Button pushed function: NextiterationButton
        function NextiterationButtonPushed(app, event)
            app.isClickedNextIteration = 1;
        end

        % Button pushed function: GotoendButton
        function GotoendButtonPushed(app, event)
            app.isClickedgotoEnd = 1;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isClickedStop = 1;
            set(app.StopButton,'Enable','off');
            set(app.ResumeButton,'Enable','on');
            set(app.ClearAllButton,'Enable','off');
            stop = app.isClickedStop;
            while stop == 1
                pause(0.1);
                stop = app.isClickedStop;
                if (app.isClickedgotoEnd == 1)
                    break;
                end    
            end 
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.isClickedStop = 0;
            set(app.ResumeButton,'Enable','off');
            set(app.StopButton,'Enable','on');
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            try
            app.gxEditField.Value = '';
            app.x0EditField.Value = 0;
            app.qEditField.Value = 0;
            app.epsEditField.Value = 0;
            app.nEditField.Value = 0;
            app.bEditField.Value = 0;
            app.aEditField.Value = 0;
            app.AutoCheckBox.Value = 0;
            app.TextArea.Value = '';
            app.ROOTEditField.Value = 0;
            cla(app.UIAxes);
            cla(app.UIAxes2);
            catch ME
                app.TextArea.Value = ME.message;
            end
        end

        % Value changed function: PlottingonlyButton
        function PlottingonlyButtonValueChanged(app, event)
            if (app.isClickedPlottingOnly == 0)
                app.isClickedPlottingOnly = 1;
                set(app.epsEditField,'Enable','off');
                set(app.qEditField,'Enable','off');
                set(app.x0EditField,'Enable','off');
                set(app.nEditField,'Enable','off');
                set(app.AutoCheckBox,'Enable','off');
                set(app.ClearAllButton,'Enable','off');
                set(app.StopButton,'Enable','off');
                set(app.GotoendButton,'Enable','off');
                set(app.NextiterationButton,'Enable','off');
                set(app.ResumeButton,'Enable','off');
                set(app.FindRootButton,'Enable','off');
            else
                app.isClickedPlottingOnly = 0;
                set(app.epsEditField,'Enable','on');
                set(app.qEditField,'Enable','on');
                set(app.x0EditField,'Enable','on');
                set(app.nEditField,'Enable','on');
                set(app.AutoCheckBox,'Enable','on');
                set(app.ClearAllButton,'Enable','on');
                set(app.FindRootButton,'Enable','on');
            end 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IterativeMethodUIFigure = uifigure;
            app.IterativeMethodUIFigure.Name = 'Iterative Method';
            app.Panel_2 = uipanel(app.IterativeMethodUIFigure);
            app.gxEditFieldLabel = uilabel(app.Panel_2);
            app.gxEditFieldLabel.Text = 'g(x) =';
            app.gxEditField = uieditfield(app.Panel_2, 'text');
            app.gxEditField.ValueChangedFcn = createCallbackFcn(app, @gxEditFieldValueChanged, true);
            app.x0EditFieldLabel = uilabel(app.Panel_2);
            app.x0EditFieldLabel.Text = 'x0 =';
            app.x0EditField = uieditfield(app.Panel_2, 'numeric');
            app.qEditFieldLabel = uilabel(app.Panel_2);
            app.qEditFieldLabel.Text = 'q =';
            app.qEditField = uieditfield(app.Panel_2, 'numeric');
            app.epsLabel = uilabel(app.Panel_2);
            app.epsLabel.Text = 'eps =';
            app.epsEditField = uieditfield(app.Panel_2, 'numeric');
            app.MethodInputsLabel = uilabel(app.Panel_2);
            app.MethodInputsLabel.Text = 'Method Inputs';
            app.PlotfunctionButton = uibutton(app.Panel_2, 'push');
            app.PlotfunctionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotfunctionButtonPushed, true);
            app.PlotfunctionButton.Text = 'Plot function';
            app.ToleranceLabel = uilabel(app.Panel_2);
            app.ToleranceLabel.Text = 'Tolerance';
            app.FindRootButton = uibutton(app.Panel_2, 'push');
            app.FindRootButton.ButtonPushedFcn = createCallbackFcn(app, @FindRootButtonPushed, true);
            app.FindRootButton.Text = 'Find Root ';
            app.StopButton = uibutton(app.Panel_2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GotoendButton = uibutton(app.Panel_2, 'push');
            app.GotoendButton.ButtonPushedFcn = createCallbackFcn(app, @GotoendButtonPushed, true);
            app.GotoendButton.Text = 'Go to end';
            app.NextiterationButton = uibutton(app.Panel_2, 'push');
            app.NextiterationButton.ButtonPushedFcn = createCallbackFcn(app, @NextiterationButtonPushed, true);
            app.NextiterationButton.Text = 'Next iteration';
            app.IterativefunctionLabel = uilabel(app.Panel_2);
            app.IterativefunctionLabel.Text = 'Iterative function:';
            app.AutoCheckBox = uicheckbox(app.Panel_2);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = 'a =';
            app.aEditField = uieditfield(app.Panel_2, 'numeric');
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = 'b =';
            app.bEditField = uieditfield(app.Panel_2, 'numeric');
            app.nEditFieldLabel = uilabel(app.Panel_2);
            app.nEditFieldLabel.Text = 'n =';
            app.nEditField = uieditfield(app.Panel_2, 'numeric');
            app.ResumeButton = uibutton(app.Panel_2, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.ClearAllButton = uibutton(app.Panel_2, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.PlottingonlyButton = uibutton(app.Panel_2, 'state');
            app.PlottingonlyButton.ValueChangedFcn = createCallbackFcn(app, @PlottingonlyButtonValueChanged, true);
            app.PlottingonlyButton.Text = 'Plotting only';
            app.Panel = uipanel(app.IterativeMethodUIFigure);
            app.FunctionplotLabel = uilabel(app.Panel);
            app.FunctionplotLabel.Text = 'Function plot';
            app.UIAxes = uiaxes(app.Panel);
            app.OgnjenSimic9318Label = uilabel(app.Panel);
            app.OgnjenSimic9318Label.Text = 'Ognjen Simic 93/18';
            app.Panel_3 = uipanel(app.IterativeMethodUIFigure);
            app.ErrorplotLabel = uilabel(app.Panel_3);
            app.ErrorplotLabel.Text = 'Error plot';
            app.UIAxes2 = uiaxes(app.Panel_3);
            app.ResultsLabel = uilabel(app.IterativeMethodUIFigure);
            app.ResultsLabel.Text = 'Results:';
            app.TextArea = uitextarea(app.IterativeMethodUIFigure);
            app.TextArea.Editable = 'off';
            app.ROOTEditFieldLabel = uilabel(app.IterativeMethodUIFigure);
            app.ROOTEditFieldLabel.Text = 'ROOT:';
            app.ROOTEditField = uieditfield(app.IterativeMethodUIFigure, 'numeric');
            app.ROOTEditField.ValueDisplayFormat = '%.10f';
            app.ROOTEditField.Editable = 'off';
            app.ResultLabel = uilabel(app.IterativeMethodUIFigure);
            app.ResultLabel.Text = 'Result';
        end
    end

    methods (Access = public)

        % Construct app
        function app = IterativeMethodAPP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IterativeMethodUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IterativeMethodUIFigure)
        end
    end
end